classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        N1EditField              matlab.ui.control.NumericEditField
        N1EditFieldLabel         matlab.ui.control.Label
        N2EditField              matlab.ui.control.NumericEditField
        N2EditFieldLabel         matlab.ui.control.Label
        StartTimeEditField       matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel  matlab.ui.control.Label
        GenerateSignalButton     matlab.ui.control.Button
        ComparewithbeadsButton   matlab.ui.control.Button
        LoadBeadsdataButton      matlab.ui.control.Button
        TEditField               matlab.ui.control.NumericEditField
        TEditFieldLabel          matlab.ui.control.Label
        BetaEditField            matlab.ui.control.NumericEditField
        BetaEditFieldLabel       matlab.ui.control.Label
        lambdaEditField          matlab.ui.control.NumericEditField
        lambdaEditFieldLabel     matlab.ui.control.Label
        NevEditField             matlab.ui.control.NumericEditField
        NevEditFieldLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SynT
        SynY
        SynFs
        SynNoise
        BeadsBinFile
        BeadsFs = 200000
        BeadsN
        BeadsTime
        BeadsSignal
        BeadsDuration
    end

    
    methods (Access = private)
        
         function [tExp, iExp_nA] = readBeadsSegment(app, startTime, duration)

    Fs = app.BeadsFs;

    startSample = floor(startTime * Fs) + 1;
    numSamples = floor(duration * Fs);

    if startSample < 1
        startSample = 1;
    end

    if startSample + numSamples - 1 > app.BeadsN
        numSamples = app.BeadsN - startSample + 1;
    end

    fid = fopen(app.BeadsBinFile, 'r');

    if fid == -1
        error('Could not open beads bin file.');
    end

    fseek(fid, (startSample - 1) * 4, 'bof');

    voltage_V = fread(fid, numSamples, 'single');

    fclose(fid);

    tExp = (0:length(voltage_V)-1)' / Fs + startTime;
    iExp_nA = 10 * voltage_V;

         end
         function [f, Sn] = normalizedPowerSpectrum(app, x, Fs)

             x = x(:);              % make signal a column vector
             x = x(~isnan(x));      % remove NaN values if any

             N = length(x);

             X = fft(x);            % FFT of time signal
             S = abs(X).^2;         % power spectrum

             % Keep only positive frequencies
             K = floor(N/2) + 1;
             S = S(1:K);

             f = (0:K-1)' * (Fs/N); % frequency axis in Hz

             % Normalize by S(0), which is the first FFT power value
             S0 = S(1);

             if S0 == 0 || ~isfinite(S0)
                 S0 = max(S);
             end

             Sn = S / S0;

             % Remove zero frequency for log-log plotting
             f = f(2:end);
             Sn = Sn(2:end);

         end
         function epsilon = spectrumError(app, xExp, xSynth, Fs)

    [fExp, SnExp] = app.normalizedPowerSpectrum(xExp, Fs);
    [fSynth, SnSynth] = app.normalizedPowerSpectrum(xSynth, Fs);

    % Compare only common frequency range
     fMin = max(min(fExp), min(fSynth));
     fMax = min(max(fExp), max(fSynth));

    idx = fExp >= fMin & fExp <= fMax;

    fCommon = fExp(idx);
    SnExpCommon = SnExp(idx);

    % Interpolate synthetic spectrum onto experimental frequency points
    SnSynthCommon = interp1(fSynth, SnSynth, fCommon, 'linear');

     % Remove invalid points
             valid = isfinite(SnExpCommon) & isfinite(SnSynthCommon);

             diff = SnExpCommon(valid) - SnSynthCommon(valid);

             % Professor's error formula
             epsilon = sum(diff.^2);


end
         function xSynth = makeSyntheticSignal(app, N, T, N1_val, N2_val, beta_val, lambda_val, N_ev_val)

             % ==========================================
             % TIME AND FREQUENCY VECTORS
             % ==========================================
             t = linspace(0, T, N)';
             dt = t(2) - t(1);
             Fs = 1 / dt;

             f = (0:N-1)' * (Fs / N);
             f(1) = 1e-9;

             % ==========================================
             % NOISE GENERATION: N1 + N2/f
             % ==========================================
             S_f = N1_val + (N2_val ./ f);
             mag_S = sqrt(S_f);

             randomPhase = 2*pi*rand(N,1);
             noiseFreq = mag_S .* exp(1i * randomPhase);

             noiseSignal = real(ifft(noiseFreq));

             % Normalize noise so it behaves well
             noiseSignal = noiseSignal - mean(noiseSignal);
             noiseSignal = noiseSignal / max(abs(noiseSignal));

             xSynth = noiseSignal;

             % ==========================================
             % ADD BEAD EVENTS
             % ==========================================
             eventTimes = sort(rand(N_ev_val,1) * T);

             for k = 1:N_ev_val

                 eventStart = eventTimes(k);

                 idx = t >= eventStart;

                 % exponential shaped current blockade
                 eventShape = -beta_val * exp(-lambda_val * (t(idx) - eventStart));

                 xSynth(idx) = xSynth(idx) + eventShape;

             end

         end
        function epsilon = fittingObjective(app, q, xExp, Fs, T_val, N_ev_val)

    % =====================================================
    % PARAMETER LIMITS
    % q = log10([N1, N2, Beta, lambda])
    % =====================================================

    qLower = log10([
        1e-8    % minimum N1
        1e-12   % minimum N2
        1e-6    % minimum Beta
        1       % minimum lambda
    ]);

    qUpper = log10([
        1       % maximum N1
        1e-1    % maximum N2
        1e-1    % maximum Beta
        5000    % maximum lambda
    ]);

    % If optimizer tries values outside the allowed range,
    % give it a huge error so it avoids those values
    if any(q < qLower) || any(q > qUpper)
        epsilon = 1e20;
        return;
    end

    % Convert from log scale to actual parameter values
    params = 10.^q;

    N1_val     = params(1);
    N2_val     = params(2);
    beta_val   = params(3);
    lambda_val = params(4);

    N = length(xExp);

    % Fix randomness so each trial is comparable
    rng(1)

    % Generate synthetic signal
    xSynth = app.makeSyntheticSignal(N, T_val, N1_val, N2_val, beta_val, lambda_val, N_ev_val);

    % Calculate professor's error
    epsilon = app.spectrumError(xExp, xSynth, Fs);

    % Safety
    if ~isfinite(epsilon)
        epsilon = 1e20;
    end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
% 1. Get the current values from the UI input fields
  beta_val = app.BetaEditField.Value;
  T_val = app.TEditField.Value;
  N1_val = app.N1EditField.Value; 
  N2_val = app.N2EditField.Value;
  N_ev_val   = round(app.NevEditField.Value);
  lambda_val = app.lambdaEditField.Value;


% 2. Generate the time vector and frequency vector

  Fs = 200000;                 % Same as experimental beads data: 200 kHz
  N = round(Fs * T_val);             % Number of synthetic samples

  t = (0:N-1) / Fs;            % Time vector

  f = (0:N-1) * (Fs / N);      % Frequency vector
  f(1) = 1e-9;                 % Prevent division by zero at DC
            % ==========================================
 % --- YOUR NOISE ALGORITHM ---

   S_f = N1_val + (N2_val ./ f);
   mag_S = sqrt(S_f);
   random_phase = exp(1i * 2 * pi * rand(1, N));
   complex_S = mag_S .* random_phase;
   b_val = ifft(complex_S);
   c_val = abs(b_val);
   noise_t = c_val - mean(c_val);
   app.SynNoise = noise_t;

            % ==========================================
 % PULSE TRAIN GENERATION (Molecules)

   pulse_train = zeros(size(t));

 % Random start times (Uniform distribution)
   t_i = T_val * rand(1, N_ev_val);

 % Random duration (Exponential distribution)
   delt_i = -log(rand(1, N_ev_val)) / lambda_val;

 % Drop signal to -1 when a molecule is in the pore
   for k = 1:N_ev_val
       active_indices = (t >= t_i(k)) & (t < (t_i(k) + delt_i(k)));
       pulse_train(active_indices) = -1;
   end

            % ==========================================
 % 5. BASELINE DRIFT (Slow Sine Wave)

   baseline_drift = sin(beta_val * t);

            % ==========================================
 % 6. FINAL SIGNAL ASSEMBLY & PLOT

            % y(t) = drift + pulses + noise
   final_y = baseline_drift + pulse_train + noise_t;
            % ==========================================


 % Store synthetic signal for later comparison
    app.SynT = t(:);
    app.SynY = final_y(:);
    app.SynFs = Fs;

 % Draw the plot
    plot(app.UIAxes, t, final_y, 'LineWidth', 1.2);
    title(app.UIAxes, 'Synthetic Nanopore Signal');
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Current (Normalized)');
    grid(app.UIAxes, 'on');

        end

        % Button pushed function: LoadBeadsdataButton
        function LoadBeadsdataButtonPushed(app, event)
            [file, path] = uigetfile('*.bin', 'Select beads2_voltage_single.bin');

            if isequal(file, 0)
                return;
            end

            app.BeadsBinFile = fullfile(path, file);

            % Your beads data sampling frequency
            app.BeadsFs = 200000;   % 200 kHz

            % The bin file stores single precision values
            % single = 4 bytes per sample
            info = dir(app.BeadsBinFile);
            app.BeadsN = floor(info.bytes / 4);

            app.BeadsDuration = app.BeadsN / app.BeadsFs;

            msg = sprintf(['Beads BIN data loaded successfully.\n\n' ...
                'File: %s\n' ...
                'Samples: %d\n' ...
                'Duration: %.2f s\n' ...
                'Duration: %.2f min\n' ...
                'Sampling frequency: %.0f Hz'], ...
                file, ...
                app.BeadsN, ...
                app.BeadsDuration, ...
                app.BeadsDuration/60, ...
                app.BeadsFs);

            uialert(app.UIFigure, msg, 'Beads Data Loaded');
            
        end

        % Button pushed function: ComparewithbeadsButton
        function ComparewithbeadsButtonPushed(app, event)
            if isempty(app.SynY)
                uialert(app.UIFigure, 'First click Generate Signal.', 'No Synthetic Signal');
                return;
            end

            if isempty(app.BeadsBinFile)
                uialert(app.UIFigure, 'First click Load Beads Data.', 'No Beads Data');
                return;
            end

            startTime = app.StartTimeEditField.Value;

            % Use the synthetic signal duration for comparison
            duration = app.SynT(end) - app.SynT(1);

            if duration > 10
                choice = uiconfirm(app.UIFigure, ...
                    'This duration is large. For smooth comparison, use T = 1 to 5 seconds. Continue anyway?', ...
                    'Large Duration Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel');

                if strcmp(choice, 'Cancel')
                    return;
                end
            end

            % Read real beads data segment
            [tExp, iExp_nA] = readBeadsSegment(app, startTime, duration);

            % Convert experimental time to relative time
            tExpRel = tExp - tExp(1);

            % Synthetic time
            tSyn = app.SynT(:);
            tSynRel = tSyn - tSyn(1);

            % Interpolate real beads data to same number of points as synthetic signal
            iExpInterp = interp1(tExpRel, iExp_nA, tSynRel, 'linear', 'extrap');
            % =====================================================
           % POWER SPECTRUM ERROR CALCULATION USING FFT
% Following professor's method:
% S(w) = |FFT{x(t)}|^2
% Sn(w) = S(w) / S(0)
% epsilon = sum((Sn_exp - Sn_syn).^2)
% =========================================================

% Use full synthetic signal for comparison
% If you only want synthetic noise, use app.SynNoise instead
xSyn = app.SynY(:);

% Real beads data interpolated to same time points as synthetic signal
xExp = iExpInterp(:);

% =========================================================
% OPTIMIZE SYNTHETIC PARAMETERS TO MINIMIZE ERROR
% =========================================================

Fs = app.SynFs;
T_val = duration;

% Initial guess from app fields
p0 = [
    app.N1EditField.Value
    app.N2EditField.Value
    app.BetaEditField.Value
    app.lambdaEditField.Value
];

% Safety: all values must be positive for log10
if any(p0 <= 0)
    uialert(app.UIFigure, ...
        'N1, N2, Beta, and lambda must all be positive for optimization.', ...
        'Invalid Initial Values');
    return;
end

% Work in log scale so values stay positive
q0 = log10(p0);

N_ev_val = round(app.NevEditField.Value);

objective = @(q) app.fittingObjective(q, xExp, Fs, T_val, N_ev_val);

options = optimset( ...
    'Display', 'iter', ...
    'MaxIter', 60, ...
    'TolX', 1e-3, ...
    'TolFun', 1e-6);

[qBest, bestError] = fminsearch(objective, q0, options);

bestParams = 10.^qBest;

bestN1 = bestParams(1);
bestN2 = bestParams(2);
bestBeta = bestParams(3);
bestLambda = bestParams(4);

% Update app fields with best values
app.N1EditField.Value = bestN1;
app.N2EditField.Value = bestN2;
app.BetaEditField.Value = bestBeta;
app.lambdaEditField.Value = bestLambda;

% Generate best synthetic signal
N = length(xExp);

rng(1)
xBest = app.makeSyntheticSignal(N, T_val, bestN1, bestN2, bestBeta, bestLambda, N_ev_val);

% Calculate spectra for final plot
[fExp, SnExp] = app.normalizedPowerSpectrum(xExp, Fs);
[fBest, SnBest] = app.normalizedPowerSpectrum(xBest, Fs);

% Make same length for plotting
Nplot = min(length(fExp), length(fBest));
fPlot = fExp(1:Nplot);
SnExp = SnExp(1:Nplot);
SnBest = SnBest(1:Nplot);

% =========================================================
% TEST CONSTANT OFFSET BETWEEN SYNTHETIC AND EXPERIMENTAL SPECTRA
% Since plot is log-log, test offset in log10(power)
% =========================================================

valid = isfinite(SnBest) & isfinite(SnExp) & SnBest > 0 & SnExp > 0;

logSynth = log10(SnBest(valid));
logExp   = log10(SnExp(valid));

% Average vertical offset on log-log plot
logOffset = mean(logSynth - logExp);

% Convert log offset into a multiplicative correction
offsetFactor = 10^logOffset;

% Shift synthetic spectrum down/up by this constant factor
SnBest_offset = SnBest / offsetFactor;

% Error before and after offset correction
errorBeforeOffset = sum((SnExp(valid) - SnBest(valid)).^2);

valid2 = isfinite(SnBest_offset) & isfinite(SnExp) & SnBest_offset > 0 & SnExp > 0;
errorAfterOffset = sum((SnExp(valid2) - SnBest_offset(valid2)).^2);

% Check whether offset is roughly constant
offsetVariation = std(logSynth - logExp);

cla(app.UIAxes)

loglog(app.UIAxes, fPlot, SnBest, ...
    'Color', [0.4 0.75 1.0], ...
    'LineWidth', 1.0);

hold(app.UIAxes, 'on')

loglog(app.UIAxes, fPlot, SnBest_offset, ...
    'Color', [0.1 0.9 1.0], ...
    'LineWidth', 1.5);

loglog(app.UIAxes, fPlot, SnExp, ...
    'Color', [1.0 0.65 0.25], ...
    'LineWidth', 1.2);

hold(app.UIAxes, 'off')

xlabel(app.UIAxes, 'Frequency (Hz)')
ylabel(app.UIAxes, 'Normalized Power')

title(app.UIAxes, ...
    ['Offset factor = ', num2str(offsetFactor, '%.3g'), ...
     ', Error before = ', num2str(errorBeforeOffset, '%.3g'), ...
     ', after = ', num2str(errorAfterOffset, '%.3g')])

legend(app.UIAxes, ...
    'Original synthetic', ...
    'Offset-corrected synthetic', ...
    'Real beads data')

grid(app.UIAxes, 'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NevEditFieldLabel = uilabel(app.UIFigure);
            app.NevEditFieldLabel.Text = 'Nev';
            app.NevEditField = uieditfield(app.UIFigure, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.UIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadBeadsdataButton = uibutton(app.UIFigure, 'push');
            app.LoadBeadsdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBeadsdataButtonPushed, true);
            app.LoadBeadsdataButton.Text = 'Load Beads data';
            app.ComparewithbeadsButton = uibutton(app.UIFigure, 'push');
            app.ComparewithbeadsButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithbeadsButtonPushed, true);
            app.ComparewithbeadsButton.Text = 'Compare with beads';
            app.GenerateSignalButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.StartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeEditFieldLabel.Text = 'StartTime';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeEditField.Value = 60;
            app.N2EditFieldLabel = uilabel(app.UIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.UIFigure, 'numeric');
            app.N1EditFieldLabel = uilabel(app.UIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end