classdef nanoporenoise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SyntheticsignalTab             matlab.ui.container.Tab
        GenerateSyntheticSignalButton  matlab.ui.control.Button
        NevEditField                   matlab.ui.control.NumericEditField
        NevEditFieldLabel              matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.NumericEditField
        lambdaEditFieldLabel           matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.NumericEditField
        BetaEditFieldLabel             matlab.ui.control.Label
        N2EditField                    matlab.ui.control.NumericEditField
        N2EditFieldLabel               matlab.ui.control.Label
        N1EditField                    matlab.ui.control.NumericEditField
        N1EditFieldLabel               matlab.ui.control.Label
        TEditField                     matlab.ui.control.NumericEditField
        TEditFieldLabel                matlab.ui.control.Label
        FsEditField                    matlab.ui.control.NumericEditField
        FsLabel                        matlab.ui.control.Label
        ExperimentaldataTab            matlab.ui.container.Tab
        Fs_eEditField                  matlab.ui.control.NumericEditField
        Fs_eEditFieldLabel             matlab.ui.control.Label
        ScaleFactorEditField           matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        FileTypeDropDown               matlab.ui.control.DropDown
        FileTypeDropDownLabel          matlab.ui.control.Label
        LoadExperimentalDataButton     matlab.ui.control.Button
        SpectrummatchingTab            matlab.ui.container.Tab
        BestErrorEditField             matlab.ui.control.NumericEditField
        BestErrorEditFieldLabel        matlab.ui.control.Label
        OffsetFactorEditField          matlab.ui.control.NumericEditField
        OffsetFactorEditFieldLabel     matlab.ui.control.Label
        AxisTypeDropDown               matlab.ui.control.DropDown
        AxisTypeDropDownLabel          matlab.ui.control.Label
        PlotTypeDropDown               matlab.ui.control.DropDown
        PlotTypeDropDownLabel          matlab.ui.control.Label
        OptimizeParametersButton       matlab.ui.control.Button
        CompareSpectraButton           matlab.ui.control.Button
        ExportTab                      matlab.ui.container.Tab
        SpectrumDataButton             matlab.ui.control.Button
        SavePlotButton                 matlab.ui.control.Button
        OptimizedParametersButton      matlab.ui.control.Button
        SyntheticSignalButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Synthetic signal
    SynT
    SynY
    SynFs
    SynNoise

    % Experimental data
    ExpFile
    ExpFileType
    ExpFs = 200000
    ExpN
    ExpDuration
    ExpT
    ExpY

    % Spectrum results
    SpectrumFrequency
    SpectrumExperimental
    SpectrumSynthetic
    SpectrumSyntheticOffset

    % Optimization / fitting results
    BestError
    OffsetFactor
    OffsetVariation
    BestN1
    BestN2
    BestBeta
    BestLambda

    end

    methods (Access = private)
        
        function [tExp, yExp] = readExperimentalSegment(app, startTime, duration)

    Fs = app.ExpFs;
    scaleFactor = app.ScaleFactorEditField.Value;

    if strcmp(app.ExpFileType, 'bin')

        startSample = floor(startTime * Fs) + 1;
        numSamples = floor(duration * Fs);

        if startSample < 1
            startSample = 1;
        end

        if startSample + numSamples - 1 > app.ExpN
            numSamples = app.ExpN - startSample + 1;
        end

    fid = fopen(app.ExpFile, 'r');

        if fid == -1
            error('Could not open experimental BIN file.');
        end

    fseek(fid, (startSample - 1) * 4, 'bof');

    rawSignal = fread(fid, numSamples, 'single');

    fclose(fid);

    tExp = (0:length(rawSignal)-1)' / Fs + startTime;
    yExp = scaleFactor * rawSignal;

    else
        % CSV data already loaded into app.ExpT and app.ExpY
        idx = app.ExpT >= startTime & app.ExpT <= startTime + duration;

        tExp = app.ExpT(idx);
        yExp = app.ExpY(idx);
    end

        end
         function [f, Sn] = normalizedPowerSpectrum(~, x, Fs)

             x = x(:);              % make signal a column vector
             x = x(~isnan(x));      % remove NaN values if any

             N = length(x);

             X = fft(x);            % FFT of time signal
             S = abs(X).^2;         % power spectrum

             % Keep only positive frequencies
             K = floor(N/2) + 1;
             S = S(1:K);

             f = (0:K-1)' * (Fs/N); % frequency axis in Hz

             % Normalize by S(0), which is the first FFT power value
             S0 = S(1);

             if S0 == 0 || ~isfinite(S0)
                 S0 = max(S);
             end

             Sn = S / S0;

             % Remove zero frequency for log-log plotting
             f = f(2:end);
             Sn = Sn(2:end);

         end
         function epsilon = spectrumError(app, xExp, xSynth, Fs)

    [fExp, SnExp] = app.normalizedPowerSpectrum(xExp, Fs);
    [fSynth, SnSynth] = app.normalizedPowerSpectrum(xSynth, Fs);

    % Compare only common frequency range
     fMin = max(min(fExp), min(fSynth));
     fMax = min(max(fExp), max(fSynth));

    idx = fExp >= fMin & fExp <= fMax;

    fCommon = fExp(idx);
    SnExpCommon = SnExp(idx);

    % Interpolate synthetic spectrum onto experimental frequency points
    SnSynthCommon = interp1(fSynth, SnSynth, fCommon, 'linear');

     % Remove invalid points
             valid = isfinite(SnExpCommon) & isfinite(SnSynthCommon);

             diff = SnExpCommon(valid) - SnSynthCommon(valid);

             % Professor's error formula
             epsilon = sum(diff.^2);


        end
        function xSynth = makeSyntheticSignal(~, N, T, N1_val, N2_val, beta_val, lambda_val, N_ev_val)

            
             % TIME AND FREQUENCY VECTORS           
             t = linspace(0, T, N)';
             dt = t(2) - t(1);
             Fs = 1 / dt;

             f = (0:N-1)' * (Fs / N);
             f(1) = 1e-9;

           
             % NOISE GENERATION: N1 + N2/f            
             S_f = N1_val + (N2_val ./ f);
             mag_S = sqrt(S_f);
             randomPhase = 2*pi*rand(N,1);
             noiseFreq = mag_S .* exp(1i * randomPhase);
             noiseSignal = real(ifft(noiseFreq));

             % Normalize noise so it behaves well
             noiseSignal = noiseSignal - mean(noiseSignal);
             noiseSignal = noiseSignal / max(abs(noiseSignal));

             xSynth = noiseSignal;

           
             % ADD BEAD EVENTS
             eventTimes = sort(rand(N_ev_val,1) * T);

             for k = 1:N_ev_val

                 eventStart = eventTimes(k);

                 idx = t >= eventStart;

                 % exponential shaped current blockade
                 eventShape = -beta_val * exp(-lambda_val * (t(idx) - eventStart));

                 xSynth(idx) = xSynth(idx) + eventShape;

             end

         end
        function epsilon = fittingObjective(app, q, xExp, Fs, T_val, N_ev_val)

    % PARAMETER LIMITS
    % q = log10([N1, N2, Beta, lambda])
   
    qLower = log10([
        1e-8    % minimum N1
        1e-12   % minimum N2
        1e-6    % minimum Beta
        1       % minimum lambda
    ]);

    qUpper = log10([
        1       % maximum N1
        1e-1    % maximum N2
        1e-1    % maximum Beta
        5000    % maximum lambda
    ]);

    % If optimizer tries values outside the allowed range,
    % give it a huge error so it avoids those values
    if any(q < qLower) || any(q > qUpper)
        epsilon = 1e20;
        return;
    end

    % Convert from log scale to actual parameter values
    params = 10.^q;

    N1_val     = params(1);
    N2_val     = params(2);
    beta_val   = params(3);
    lambda_val = params(4);

    N = length(xExp);

    % Fix randomness so each trial is comparable
    rng(1)

    % Generate synthetic signal
    xSynth = app.makeSyntheticSignal(N, T_val, N1_val, N2_val, beta_val, lambda_val, N_ev_val);

    % Calculate professor's error
    epsilon = app.spectrumError(xExp, xSynth, Fs);

    % Safety
    if ~isfinite(epsilon)
        epsilon = 1e20;
    end

        end
        function [xExp, xSyn, Fs] = prepareComparisonSignals(app)

            if isempty(app.SynY)
                uialert(app.UIFigure, ...
                    'First generate a synthetic signal.', ...
                    'No Synthetic Signal');
                error('No synthetic signal.');
            end

            if isempty(app.ExpFile)
                uialert(app.UIFigure, ...
                    'First load experimental data.', ...
                    'No Experimental Data');
                error('No experimental data.');
            end

            startTime = app.StartTimeEditField.Value;
            duration = app.TEditField.Value;

            [tExp, yExp] = app.readExperimentalSegment(startTime, duration);

            tExpRel = tExp - tExp(1);

            tSyn = app.SynT(:);
            tSynRel = tSyn - tSyn(1);

            yExpInterp = interp1(tExpRel, yExp, tSynRel, 'linear', 'extrap');

            xExp = yExpInterp(:);
            xSyn = app.SynY(:);

            N = min(length(xExp), length(xSyn));

            xExp = xExp(1:N);
            xSyn = xSyn(1:N);

            Fs = app.SynFs;

        end
        function [SnSynthOffset, offsetFactor, offsetVariation] = applyConstantOffset(~, SnSynth, SnExp)

            valid = isfinite(SnSynth) & isfinite(SnExp) & ...
                SnSynth > 0 & SnExp > 0;

            logSynth = log10(SnSynth(valid));
            logExp = log10(SnExp(valid));

            logOffset = mean(logSynth - logExp);

            offsetFactor = 10^logOffset;

            SnSynthOffset = SnSynth / offsetFactor;

            offsetVariation = std(logSynth - logExp);

        end
        function plotSpectrumResults(app)

            fPlot = app.SpectrumFrequency;
            SnExp = app.SpectrumExperimental;
            SnSyn = app.SpectrumSynthetic;
            SnSynOffset = app.SpectrumSyntheticOffset;

            cla(app.UIAxes)

            axisType = app.AxisTypeDropDown.Value;

            if strcmpi(axisType, 'Log-Log')

                loglog(app.UIAxes, fPlot, SnSyn, ...
                    'Color', [0.4 0.75 1.0], ...
                    'LineWidth', 1.0);

                hold(app.UIAxes, 'on')

                loglog(app.UIAxes, fPlot, SnSynOffset, ...
                    'Color', [0.1 0.9 1.0], ...
                    'LineWidth', 1.5);

                loglog(app.UIAxes, fPlot, SnExp, ...
                    'Color', [1.0 0.65 0.25], ...
                    'LineWidth', 1.2);

            else

                plot(app.UIAxes, fPlot, SnSyn, ...
                    'Color', [0.4 0.75 1.0], ...
                    'LineWidth', 1.0);

                hold(app.UIAxes, 'on')

                plot(app.UIAxes, fPlot, SnSynOffset, ...
                    'Color', [0.1 0.9 1.0], ...
                    'LineWidth', 1.5);

                plot(app.UIAxes, fPlot, SnExp, ...
                    'Color', [1.0 0.65 0.25], ...
                    'LineWidth', 1.2);

            end

            hold(app.UIAxes, 'off')

            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Normalized Power')

            title(app.UIAxes, ...
                ['Error = ', num2str(app.BestError, '%.4g'), ...
                ', Offset factor = ', num2str(app.OffsetFactor, '%.3g')])

            legend(app.UIAxes, ...
                'Synthetic', ...
                'Offset-corrected synthetic', ...
                'Experimental data')

            grid(app.UIAxes, 'on')

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSyntheticSignalButton
        function GenerateSyntheticSignalButtonPushed(app, event)
            % 1. Get the current values from the UI input fields
            beta_val = app.BetaEditField.Value;
            T_val = app.TEditField.Value;
            N1_val = app.N1EditField.Value; 
            N2_val = app.N2EditField.Value;
            N_ev_val   = round(app.NevEditField.Value);
            lambda_val = app.lambdaEditField.Value;


            % 2. Generate the time vector and frequency vector
           
            Fs = app.FsEditField.Value;                
            if app.FsEditField.Value <= 0 || app.TEditField.Value <= 0
                uialert(app.UIFigure, ...
                    'Fs and T must be greater than zero.', ...
                    'Invalid Synthetic Parameters');
                return;
            end
            N = round(Fs * T_val);       % Number of synthetic samples

            t = (0:N-1) / Fs;            % Time vector

            f = (0:N-1) * (Fs / N);      % Frequency vector
            f(1) = 1e-9;                 % Prevent division by zero at DC
          
            % --- YOUR NOISE ALGORITHM ---

            S_f = N1_val + (N2_val ./ f);
            mag_S = sqrt(S_f);
            random_phase = exp(1i * 2 * pi * rand(1, N));
            complex_S = mag_S .* random_phase;
            b_val = ifft(complex_S);
            c_val = abs(b_val);
            noise_t = c_val - mean(c_val);
            app.SynNoise = noise_t;

           
            % PULSE TRAIN GENERATION (Molecules)

            pulse_train = zeros(size(t));

            % Random start times (Uniform distribution)
            t_i = T_val * rand(1, N_ev_val);

            % Random duration (Exponential distribution)
            delt_i = -log(rand(1, N_ev_val)) / lambda_val;

            % Drop signal to -1 when a molecule is in the pore
            for k = 1:N_ev_val
                active_indices = (t >= t_i(k)) & (t < (t_i(k) + delt_i(k)));
                pulse_train(active_indices) = -1;
            end

            % 5. BASELINE DRIFT (Slow Sine Wave)
            baseline_drift = sin(beta_val * t);

            % 6. FINAL SIGNAL ASSEMBLY & PLOT
            % y(t) = drift + pulses + noise
            final_y = baseline_drift + pulse_train + noise_t;
          
            % Store synthetic signal for later comparison
            app.SynT = t(:);
            app.SynY = final_y(:);
            app.SynFs = Fs;

            % Draw the plot
            plot(app.UIAxes, t, final_y, 'LineWidth', 1.2);
            title(app.UIAxes, 'Synthetic Nanopore Signal');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Current (Normalized)');
            grid(app.UIAxes, 'on');

        end

        % Button pushed function: LoadExperimentalDataButton
        function LoadExperimentalDataButtonPushed(app, event)
            fileType = lower(app.FileTypeDropDown.Value);
    scaleFactor = app.ScaleFactorEditField.Value;

    switch fileType

       
        % CASE 1: BIN FILE
        case 'bin'

            [file, path] = uigetfile('*.bin', 'Select experimental BIN file');

            if isequal(file, 0)
                return;
            end

            app.ExpFile = fullfile(path, file);
            app.ExpFileType = 'bin';

            app.ExpFs = app.Fs_eEditField.Value;

            if app.ExpFs <= 0
                uialert(app.UIFigure, ...
                    'For BIN files, please enter the experimental sampling frequency Fs_e.', ...
                    'Missing Fs_e');
                return;
            end

            info = dir(app.ExpFile);

            % Assumes single precision data: 4 bytes per sample
            app.ExpN = floor(info.bytes / 4);
            app.ExpDuration = app.ExpN / app.ExpFs;

            % Do not load full BIN into memory yet
            app.ExpT = [];
            app.ExpY = [];

        % CASE 2: CSV FILE
        case 'csv'

            [file, path] = uigetfile('*.csv', 'Select experimental CSV file');

            if isequal(file, 0)
                return;
            end

            app.ExpFile = fullfile(path, file);
            app.ExpFileType = 'csv';

            data = readmatrix(app.ExpFile);

            data = data(~all(isnan(data), 2), :);  % remove empty rows

            if size(data, 2) >= 2
                % Assumption: column 1 = time, column 2 = signal
                app.ExpT = data(:,1);
                app.ExpY = scaleFactor * data(:,2);

                dt = median(diff(app.ExpT));
                app.ExpFs = 1 / dt;
                app.Fs_eEditField.Value = app.ExpFs;

            else
                % Single-column CSV: only signal values
                app.ExpFs = app.Fs_eEditField.Value;

                if app.ExpFs <= 0
                    uialert(app.UIFigure, ...
                        'Single-column CSV files need Fs_e to be entered.', ...
                        'Missing Fs_e');
                    return;
                end

                app.ExpY = scaleFactor * data(:,1);
                app.ExpT = (0:length(app.ExpY)-1)' / app.ExpFs;
            end

            app.ExpN = length(app.ExpY);
            app.ExpDuration = app.ExpN / app.ExpFs;


        % CASE 3: EXCEL FILE: XLSX OR XLS
        case {'xlsx', 'xls'}

            [file, path] = uigetfile({'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'}, ...
                                     'Select experimental Excel file');

            if isequal(file, 0)
                return;
            end

            app.ExpFile = fullfile(path, file);
            app.ExpFileType = fileType;

            data = readmatrix(app.ExpFile);

            data = data(~all(isnan(data), 2), :);  % remove empty rows

            if size(data, 2) >= 2
                % Assumption: column 1 = time, column 2 = signal
                app.ExpT = data(:,1);
                app.ExpY = scaleFactor * data(:,2);

                dt = median(diff(app.ExpT));
                app.ExpFs = 1 / dt;
                app.Fs_eEditField.Value = app.ExpFs;

            else
                % Single-column Excel file: only signal values
                app.ExpFs = app.Fs_eEditField.Value;

                if app.ExpFs <= 0
                    uialert(app.UIFigure, ...
                        'Single-column Excel files need Fs_e to be entered.', ...
                        'Missing Fs_e');
                    return;
                end

                app.ExpY = scaleFactor * data(:,1);
                app.ExpT = (0:length(app.ExpY)-1)' / app.ExpFs;
            end

            app.ExpN = length(app.ExpY);
            app.ExpDuration = app.ExpN / app.ExpFs;


        % CASE 4: ABF FILE
        case 'abf'

            [file, path] = uigetfile('*.abf', 'Select experimental ABF file');

            if isequal(file, 0)
                return;
            end

            app.ExpFile = fullfile(path, file);
            app.ExpFileType = 'abf';

            % ABF files need an ABF reader function.
            % Common MATLAB option: abfload
            if exist('abfload', 'file') ~= 2
                uialert(app.UIFigure, ...
                    ['ABF reading requires the function abfload.m.' newline ...
                     'Please add abfload.m to the MATLAB path, then try again.'], ...
                    'ABF Reader Missing');
                return;
            end

            % abfload usually returns:
            % data = samples x channels x sweeps
            % si = sampling interval in microseconds
            [data, si, ~] = abfload(app.ExpFile);

            % Convert sampling interval from microseconds to Hz
            app.ExpFs = 1e6 / si;
            app.Fs_eEditField.Value = app.ExpFs;

            % Use first channel and first sweep by default
            if ndims(data) == 3
                y = data(:,1,1);
            elseif size(data, 2) > 1
                y = data(:,1);
            else
                y = data(:);
            end

            app.ExpY = scaleFactor * double(y);
            app.ExpT = (0:length(app.ExpY)-1)' / app.ExpFs;

            app.ExpN = length(app.ExpY);
            app.ExpDuration = app.ExpN / app.ExpFs;

        % UNKNOWN FILE TYPE
        otherwise

            uialert(app.UIFigure, ...
                'Unsupported file type selected.', ...
                'File Type Error');
            return;
    end

    
    % SUCCESS MESSAGE


    msg = sprintf(['Experimental data loaded successfully.\n\n' ...
        'File: %s\n' ...
        'File type: %s\n' ...
        'Samples: %d\n' ...
        'Duration: %.2f s\n' ...
        'Duration: %.2f min\n' ...
        'Sampling frequency: %.2f Hz'], ...
        file, ...
        app.ExpFileType, ...
        app.ExpN, ...
        app.ExpDuration, ...
        app.ExpDuration/60, ...
        app.ExpFs);

    uialert(app.UIFigure, msg, 'Experimental Data Loaded');
        end

        % Button pushed function: CompareSpectraButton
        function CompareSpectraButtonPushed(app, event)
            [xExp, xSyn, Fs] = app.prepareComparisonSignals();

            [fExp, SnExp] = app.normalizedPowerSpectrum(xExp, Fs);
            [fSyn, SnSyn] = app.normalizedPowerSpectrum(xSyn, Fs);

            Nplot = min(length(fExp), length(fSyn));

            fPlot = fExp(1:Nplot);
            SnExp = SnExp(1:Nplot);
            SnSyn = SnSyn(1:Nplot);

            [SnSynOffset, offsetFactor, offsetVariation] = app.applyConstantOffset(SnSyn, SnExp);

            errorValue = app.spectrumError(xExp, xSyn, Fs);

            app.BestError = errorValue;
            app.OffsetFactor = offsetFactor;
            app.OffsetVariation = offsetVariation;

            app.SpectrumFrequency = fPlot;
            app.SpectrumExperimental = SnExp;
            app.SpectrumSynthetic = SnSyn;
            app.SpectrumSyntheticOffset = SnSynOffset;

            app.BestErrorEditField.Value = errorValue;
            app.OffsetFactorEditField.Value = offsetFactor;
            app.plotSpectrumResults();
        
        end

        % Button pushed function: OptimizeParametersButton
        function OptimizeParametersButtonPushed(app, event)
            [xExp, ~, Fs] = app.prepareComparisonSignals();

            duration = app.TEditField.Value;

            p0 = [
                app.N1EditField.Value
                app.N2EditField.Value
                app.BetaEditField.Value
                app.lambdaEditField.Value
                ];

            if any(p0 <= 0)
                uialert(app.UIFigure, ...
                    'N1, N2, Beta, and lambda must all be positive for optimization.', ...
                    'Invalid Initial Values');
                return;
            end

            q0 = log10(p0);

            N_ev_val = round(app.NevEditField.Value);

            objective = @(q) app.fittingObjective(q, xExp, Fs, duration, N_ev_val);

            options = optimset( ...
                'Display', 'iter', ...
                'MaxIter', 60, ...
                'TolX', 1e-3, ...
                'TolFun', 1e-6);

            [qBest, bestError] = fminsearch(objective, q0, options);

            bestParams = 10.^qBest;

            app.BestN1 = bestParams(1);
            app.BestN2 = bestParams(2);
            app.BestBeta = bestParams(3);
            app.BestLambda = bestParams(4);

            app.N1EditField.Value = app.BestN1;
            app.N2EditField.Value = app.BestN2;
            app.BetaEditField.Value = app.BestBeta;
            app.lambdaEditField.Value = app.BestLambda;

            N = length(xExp);

            rng(1)
            xBest = app.makeSyntheticSignal( ...
                N, duration, app.BestN1, app.BestN2, ...
                app.BestBeta, app.BestLambda, N_ev_val);

            [fExp, SnExp] = app.normalizedPowerSpectrum(xExp, Fs);
            [fBest, SnBest] = app.normalizedPowerSpectrum(xBest, Fs);

            Nplot = min(length(fExp), length(fBest));

            fPlot = fExp(1:Nplot);
            SnExp = SnExp(1:Nplot);
            SnBest = SnBest(1:Nplot);

            [SnBestOffset, offsetFactor, offsetVariation] = app.applyConstantOffset(SnBest, SnExp);

            app.BestError = bestError;
            app.OffsetFactor = offsetFactor;
            app.OffsetVariation = offsetVariation;

            app.SpectrumFrequency = fPlot;
            app.SpectrumExperimental = SnExp;
            app.SpectrumSynthetic = SnBest;
            app.SpectrumSyntheticOffset = SnBestOffset;

            app.BestErrorEditField.Value = bestError;
            app.OffsetFactorEditField.Value = offsetFactor;

            app.plotSpectrumResults();
        end

        % Button pushed function: SyntheticSignalButton
        function SyntheticSignalButtonPushed(app, event)

                if isempty(app.SynY)
                    uialert(app.UIFigure, ...
                        'No synthetic signal to export.', ...
                        'Export Error');
                    return;
                end

                data = table(app.SynT(:), app.SynY(:), ...
                    'VariableNames', {'Time_s', 'SyntheticSignal'});

                [file, path] = uiputfile('synthetic_signal.csv', ...
                    'Save Synthetic Signal');

                if isequal(file, 0)
                    return;
                end

                writetable(data, fullfile(path, file));

        
        end

        % Button pushed function: OptimizedParametersButton
        function OptimizedParametersButtonPushed(app, event)
            params = table( ...
                app.N1EditField.Value, ...
                app.N2EditField.Value, ...
                app.BetaEditField.Value, ...
                app.lambdaEditField.Value, ...
                app.NevEditField.Value, ...
                app.TEditField.Value, ...
                app.BestError, ...
                app.OffsetFactor, ...
                'VariableNames', {'N1', 'N2', 'Beta', 'Lambda', ...
                'Nev', 'T_s', 'BestError', 'OffsetFactor'});

            [file, path] = uiputfile('optimized_parameters.csv', ...
                'Save Optimized Parameters');

            if isequal(file, 0)
                return;
            end

            writetable(params, fullfile(path, file));
        end

        % Button pushed function: SpectrumDataButton
        function SpectrumDataButtonPushed(app, event)
            if isempty(app.SpectrumFrequency)
                uialert(app.UIFigure, ...
                    'No spectrum data available. Run Compare or Optimize first.', ...
                    'Export Error');
                return;
            end

            data = table( ...
                app.SpectrumFrequency(:), ...
                app.SpectrumExperimental(:), ...
                app.SpectrumSynthetic(:), ...
                app.SpectrumSyntheticOffset(:), ...
                'VariableNames', {'Frequency_Hz', ...
                'Experimental_NormalizedPower', ...
                'Synthetic_NormalizedPower', ...
                'OffsetCorrectedSynthetic_NormalizedPower'});

            [file, path] = uiputfile('spectrum_data.csv', ...
                'Save Spectrum Data');

            if isequal(file, 0)
                return;
            end

            writetable(data, fullfile(path, file));
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)

            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'}, ...
                'Save Plot');

            if isequal(file, 0)
                return;
            end

            exportgraphics(app.UIAxes, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SyntheticsignalTab = uitab(app.TabGroup);
            app.SyntheticsignalTab.Title = 'Synthetic signal';
            app.FsLabel = uilabel(app.SyntheticsignalTab);
            app.FsLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.FsEditField.Value = 200000;
            app.TEditFieldLabel = uilabel(app.SyntheticsignalTab);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.TEditField.Value = 5;
            app.N1EditFieldLabel = uilabel(app.SyntheticsignalTab);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.N1EditField.Value = 0.001;
            app.N2EditFieldLabel = uilabel(app.SyntheticsignalTab);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.N2EditField.Value = 0.02;
            app.BetaEditFieldLabel = uilabel(app.SyntheticsignalTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.BetaEditField.Value = 0.001;
            app.lambdaEditFieldLabel = uilabel(app.SyntheticsignalTab);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.lambdaEditField.Value = 300;
            app.NevEditFieldLabel = uilabel(app.SyntheticsignalTab);
            app.NevEditFieldLabel.Text = 'Nev';
            app.NevEditField = uieditfield(app.SyntheticsignalTab, 'numeric');
            app.NevEditField.Value = 3;
            app.GenerateSyntheticSignalButton = uibutton(app.SyntheticsignalTab, 'push');
            app.GenerateSyntheticSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSyntheticSignalButtonPushed, true);
            app.GenerateSyntheticSignalButton.Text = 'Generate Synthetic Signal';
            app.ExperimentaldataTab = uitab(app.TabGroup);
            app.ExperimentaldataTab.Title = 'Experimental data';
            app.LoadExperimentalDataButton = uibutton(app.ExperimentaldataTab, 'push');
            app.LoadExperimentalDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentalDataButtonPushed, true);
            app.LoadExperimentalDataButton.Text = 'Load Experimental Data';
            app.FileTypeDropDownLabel = uilabel(app.ExperimentaldataTab);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.ExperimentaldataTab);
            app.FileTypeDropDown.Items = {'bin', 'csv', 'xlsx/xls', 'abf'};
            app.FileTypeDropDown.Value = 'bin';
            app.StartTimeEditFieldLabel = uilabel(app.ExperimentaldataTab);
            app.StartTimeEditFieldLabel.Text = 'StartTime';
            app.StartTimeEditField = uieditfield(app.ExperimentaldataTab, 'numeric');
            app.StartTimeEditField.Value = 60;
            app.ScaleFactorEditFieldLabel = uilabel(app.ExperimentaldataTab);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor ';
            app.ScaleFactorEditField = uieditfield(app.ExperimentaldataTab, 'numeric');
            app.ScaleFactorEditField.Value = 1;
            app.Fs_eEditFieldLabel = uilabel(app.ExperimentaldataTab);
            app.Fs_eEditFieldLabel.Text = 'Fs_e';
            app.Fs_eEditField = uieditfield(app.ExperimentaldataTab, 'numeric');
            app.Fs_eEditField.Value = 200000;
            app.SpectrummatchingTab = uitab(app.TabGroup);
            app.SpectrummatchingTab.Title = 'Spectrum matching ';
            app.CompareSpectraButton = uibutton(app.SpectrummatchingTab, 'push');
            app.CompareSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSpectraButtonPushed, true);
            app.CompareSpectraButton.Text = 'Compare Spectra';
            app.OptimizeParametersButton = uibutton(app.SpectrummatchingTab, 'push');
            app.OptimizeParametersButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeParametersButtonPushed, true);
            app.OptimizeParametersButton.Text = 'Optimize Parameters';
            app.PlotTypeDropDownLabel = uilabel(app.SpectrummatchingTab);
            app.PlotTypeDropDownLabel.Text = 'Plot Type ';
            app.PlotTypeDropDown = uidropdown(app.SpectrummatchingTab);
            app.PlotTypeDropDown.Items = {'Time Signal', 'Power spectrum', ''};
            app.PlotTypeDropDown.Value = 'Time Signal';
            app.AxisTypeDropDownLabel = uilabel(app.SpectrummatchingTab);
            app.AxisTypeDropDownLabel.Text = 'Axis Type';
            app.AxisTypeDropDown = uidropdown(app.SpectrummatchingTab);
            app.AxisTypeDropDown.Items = {'Log-Log', 'Linear', '', ''};
            app.AxisTypeDropDown.Value = 'Log-Log';
            app.OffsetFactorEditFieldLabel = uilabel(app.SpectrummatchingTab);
            app.OffsetFactorEditFieldLabel.Text = 'Offset Factor';
            app.OffsetFactorEditField = uieditfield(app.SpectrummatchingTab, 'numeric');
            app.BestErrorEditFieldLabel = uilabel(app.SpectrummatchingTab);
            app.BestErrorEditFieldLabel.Text = 'Best Error';
            app.BestErrorEditField = uieditfield(app.SpectrummatchingTab, 'numeric');
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SyntheticSignalButton = uibutton(app.ExportTab, 'push');
            app.SyntheticSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SyntheticSignalButtonPushed, true);
            app.SyntheticSignalButton.Text = 'Synthetic Signal';
            app.OptimizedParametersButton = uibutton(app.ExportTab, 'push');
            app.OptimizedParametersButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizedParametersButtonPushed, true);
            app.OptimizedParametersButton.Text = 'Optimized Parameters';
            app.SavePlotButton = uibutton(app.ExportTab, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.SpectrumDataButton = uibutton(app.ExportTab, 'push');
            app.SpectrumDataButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumDataButtonPushed, true);
            app.SpectrumDataButton.Text = 'Spectrum Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nanoporenoise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end